classdef HyperspectralApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BandSlider                      matlab.ui.control.Slider
        BandNumberselectionSliderLabel  matlab.ui.control.Label
        BandLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Get the input image
        wavelengths
        hLineX % Cross line horizontal
        hLineY % Cross line vertical
        XCoordinateLabel % X coordinate (pxl) label
        YCoordinateLabel % Y coordinate (pxl) label
        ClickStatusLabel      
    end
    
    methods (Access = private)
        
        function updateImage(app)
            band = round(app.BandSlider.Value); % Get slider value    
            img_band = double(app.img(:,:,band));
            imagesc(app.UIAxes, img_band); % Display image in UIFigure
            axis(app.UIAxes, 'tight');
            colormap(app.UIAxes, 'gray');
            colorbar(app.UIAxes);
            uistack(app.hLineX, 'top'); 
            uistack(app.hLineY, 'top');
                % Obtenir la longueur d'onde
            if ~isempty(app.wavelengths) && band <= length(app.wavelengths)
                wavelength_value = app.wavelengths(band);
                app.BandLabel.Text = ['Band: ' num2str(band) ' (' num2str(wavelength_value) ' nm)'];
            else
                app.BandLabel.Text = ['Band: ' num2str(band)];
            end
        end
        
        function mouseMove(app, ~, ~)
            C = get(app.UIAxes, 'CurrentPoint');
            xMouse = round(C(1,1));
            yMouse = round(C(1,2));
        
            xlimVals = xlim(app.UIAxes);
            ylimVals = ylim(app.UIAxes);
            if xMouse < xlimVals(1) || xMouse > xlimVals(2) || yMouse < ylimVals(1) || yMouse > ylimVals(2)
                return;
            end
        
            % Update cross placement
            set(app.hLineX, 'XData', xlimVals, 'YData', [yMouse, yMouse]); % horizontal line 
            set(app.hLineY, 'XData', [xMouse, xMouse], 'YData', ylimVals); % vertical line 

            app.XCoordinateLabel.Text = sprintf('X: %d', xMouse);
            app.YCoordinateLabel.Text = sprintf('Y: %d', yMouse);
        end

        function onImageClick(app, src, event)
            % Get clic mouse position
            C = get(app.UIAxes, 'CurrentPoint');
            xClicked = round(C(1,1));
            yClicked = round(C(1,2));
        
            % Check if clic is inside the ploted image
            [height, width, ~] = size(app.img);
            if xClicked >= 1 && xClicked <= width && yClicked >= 1 && yClicked <= height
                app.ClickStatusLabel.Text = sprintf('Click Status: Last clicked at (%d, %d)', xClicked, yClicked);
                
                % open another windows when clicked
                openPixelWindow(app, xClicked, yClicked);
            else
                app.ClickStatusLabel.Text = 'Click Status: Click outside image';
            end
        end

        function openPixelWindow(app, x, y)
            newFig = uifigure('Name', 'Pixel Information', 'Position', [100, 100, 400, 300]);
            pixelValues = squeeze(app.img(y, x, :));
            ax = axes(newFig, 'Position', [0.15, 0.3, 0.75, 0.6]);
            if ~isempty(app.wavelengths) && length(app.wavelengths) == size(app.img, 3)
                x_values = app.wavelengths;
                x_label = 'Wavelength (nm)';
            else
                x_values = 1:size(app.img, 3);
                x_label = 'Band';
            end
            plot(ax, x_values, pixelValues, 'o-', 'LineWidth', 2, 'MarkerSize', 5);
            title(ax, sprintf('Spectral Profile for Pixel (%d, %d)', x, y));
            xlabel(ax, x_label);
            ylabel(ax, 'Intensity');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hcube = hypercube('f100826t01p00r07rdn_b_sc01_ort_img$x_88_y_3391$Agriculture.hdr'); % Load HS image
            app.img = hcube.DataCube;  % Store a global variable with de img data
            app.wavelengths = hcube.Wavelength;
            updateImage(app);

            hold(app.UIAxes, 'on');
            % Initiate mouse cross
            app.hLineX = plot(app.UIAxes, [0 0], [0 0], 'g', 'LineWidth', 2); % Ligne horizontale
            app.hLineY = plot(app.UIAxes, [0 0], [0 0], 'g', 'LineWidth', 2); % Ligne verticale

            %Update coordinate label
            app.XCoordinateLabel = uilabel(app.UIFigure, 'Text', 'X: ', 'Position', [app.UIFigure.Position(3)-100, app.UIFigure.Position(4)-30, 100, 22]);
            app.YCoordinateLabel = uilabel(app.UIFigure, 'Text', 'Y: ', 'Position', [app.UIFigure.Position(3)-100, app.UIFigure.Position(4)-50, 100, 22]);
                    
            app.UIFigure.WindowButtonMotionFcn = @(src, event) mouseMove(app, src, event);

            % Mouse click Callback
            app.ClickStatusLabel = uilabel(app.UIFigure, 'Text', 'Click Status: No click yet', 'Position', [10, app.UIFigure.Position(4)-30, 300, 22]);
            app.UIFigure.WindowButtonDownFcn = @(src, event) onImageClick(app, src, event);

        end

        % Value changed function: BandSlider
        function BandSliderValueChanged(app, event)
            updateImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hyperspectral Image from ICONES-HSI dataset')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BandLabel = uilabel(app.UIFigure);
            app.BandLabel.Text = 'Band: 1';
            app.BandNumberselectionSliderLabel = uilabel(app.UIFigure);
            app.BandNumberselectionSliderLabel.Text = 'Band Number selection';
            app.BandSlider = uislider(app.UIFigure);
            app.BandSlider.Limits = [1 220];
            app.BandSlider.MajorTicks = [1 20 40 60 80 100 120 140 160 180 200 220];
            app.BandSlider.ValueChangedFcn = createCallbackFcn(app, @BandSliderValueChanged, true);
            app.BandSlider.MinorTicks = [1 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180 184 188 192 196 200 204 208 212 220];
            app.BandSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HyperspectralApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end